classdef DP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuspensionModelApp          matlab.ui.Figure
        ControllPanel               matlab.ui.container.Panel
        KomunikanportDropDownLabel  matlab.ui.control.Label
        SerialPortList              matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.Button
        StartMeasurement            matlab.ui.control.Button
        StavpripojeniaLampLabel     matlab.ui.control.Label
        ConnectionStatusLamp        matlab.ui.control.Lamp
        StatusArea                  matlab.ui.control.TextArea
        TypmeraniaSwitchLabel       matlab.ui.control.Label
        MeasureTypeSwitch           matlab.ui.control.Switch
        OpenExportPanel             matlab.ui.control.Button
        SettingsPanel               matlab.ui.container.Panel
        DobaMeraniasLabel           matlab.ui.control.Label
        MeasTimeEditField           matlab.ui.control.NumericEditField
        SendMeasTimeButton          matlab.ui.control.Button
        SimDataPlotButton           matlab.ui.control.Button
        SmoothDataButton            matlab.ui.control.Button
        ExportPanel                 matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        UsporiadaniedtLabel         matlab.ui.control.Label
        ExportCSVPanel              matlab.ui.control.Button
        SendToWorkspaceButton       matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        CompareButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pickedSerialPort = ""; % Description
        status = "";
        message = "";
        timeout = 10;
        dobaMerania = 5;
        measureType = '2';
        device;
        connected = false;
        buttonWasPressed = false;
        values = (zeros(100,2));
        i = 0;
        simulationData_Car;
        simulationData_Car20;
        simulationData_Wheel;
        simDataVisibility = true; %true = zobrazene data tlmenej sustavy, false = zobrazene data kolesa
        smValues_tlmene;
        smValues_netlmene;
        smoothDataVisible = false;
        AccWasMeasured = false;
        t;
        porovnanieDat = false;
    end
    
    methods (Access = private)
        
        function mes = waitForResponse(app)
            for l = 0:1:app.timeout
                if app.device.NumBytesAvailable > 0
                    break;
                end
                pause(1);
            end
            mes = read(app.device,app.device.NumBytesAvailable,'string');
        end
        
        function WriteMessage(app, mes)
            app.i = app.i + 1;
            app.status(app.i,:) = mes;
            app.StatusArea.Value = app.status;
            pause(0.1);
            scroll(app.StatusArea, 'bottom');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulationData_Wheel = load('neodAY.mat');
            app.simulationData_Car20 = load('odAY20.mat');
            app.simulationData_Car = load('odAY.mat');
            app.t = load('t.mat');
            set(app.UIAxes,'XLim',[0 0.6]);
            plot(app.UIAxes,(app.t.t)',app.simulationData_Wheel.neodAY, (app.t.t)',app.simulationData_Car20.odAY20, [0 length((app.t.t)')], [-9.81 -9.81], 'LineWidth', 1.5);
            legend(app.UIAxes, "Neodpružená hmota", "Odpružená hmota", "Gravitačné zrýchlenie");
        end

        % Drop down opening function: SerialPortList
        function SerialPortListOpening(app, event)
            app.SerialPortList.Items = serialportlist("available");
        end

        % Value changed function: SerialPortList
        function SerialPortListValueChanged(app, event)
            app.pickedSerialPort = app.SerialPortList.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.pickedSerialPort = app.SerialPortList.Value;
            if app.pickedSerialPort ~= "" %Kontrolujem, ci pouzivatel zvolil seriovy port
                if app.buttonWasPressed == false
                    clear app.device;
                    delete(app.device);
                    app.ConnectButton.Enable = false;
                    app.timeout = 10;   %Cas pokial cakam na potvrdenie pripojenie (odoslanie a prijatie cisla 1 ako byte)
                    app.WriteMessage("Vytváram spojenie ...");
                    try
                        app.device = serialport(app.pickedSerialPort,230400);
                    catch ME
                        warning('Connection problem!');
                        app.WriteMessage("Problém s pripojením, skontrolujte zariadenie");
                        app.connected = false;
                        app.ConnectButton.Enable = true;
                        rethrow(ME);
                    end
                    
                    app.WriteMessage("Kontrola pripojenia");
                    write(app.device, '1', 'char');
                    pause(1);
                    
                    for j = 1:app.timeout
                        if app.device.NumBytesAvailable == 0
                            write(app.device, '1', 'char');
                            app.status(app.i,:) = app.status(app.i,:) + " .";
                            app.StatusArea.Value = app.status;
                            pause(1);
                        else
                            break;
                        end
                    end
                    
                    if app.device.NumBytesAvailable ~= 0
                        if read(app.device,app.device.NumBytesAvailable, 'char') == '1' % 1-ka ako BYTE je 49
                            app.ConnectionStatusLamp.Color = 'g';
                            app.WriteMessage("Spojenie aktívne");
                            app.ConnectButton.Text = 'ODPOJIŤ';
                            app.ConnectButton.Enable = true;
                            app.buttonWasPressed = true;
                            app.connected = true;
                        end
                    else
                        app.WriteMessage("Vyskytla sa chyba");
                        app.ConnectButton.Enable = true;
                        app.ConnectButton.Text = 'PRIPOJIŤ';
                        app.connected = false;
                    end
                else
                    clear app.device
                    delete(app.device);
                    app.ConnectButton.Text = 'PRIPOJIŤ';
                    app.buttonWasPressed = false;
                    app.connected = false;
                    app.ConnectionStatusLamp.Color = 'r';
                    app.ConnectButton.Enable = true;
                    app.WriteMessage("Odpojené");
                end
            else
                app.WriteMessage("Zvoľťe komunikačný port");
            end
            
            
        end

        % Button pushed function: StartMeasurement
        function StartMeasurementPushed(app, event)
            swValue = app.MeasureTypeSwitch.Value;
            XlimPlot = app.MeasTimeEditField.Value;
            
            switch swValue
                case 'NORMAL'
                    app.measureType = '2';
                    XlimPlot = app.MeasTimeEditField.Value;
                case 'STEP'
                    app.measureType = '3';
                    XlimPlot = app.MeasTimeEditField.Value + 0.2;
                otherwise
                    app.measureType = '2';
            end
            
            if app.connected == true
                flush(app.device);
                write(app.device, app.measureType,'char');
                app.StartMeasurement.Enable = false;
                app.WriteMessage("Prebieha meranie ");
                for k = 0:1:app.dobaMerania
                    app.status(app.i,:) = app.status(app.i,:) + ". ";
                    app.StatusArea.Value = app.status;
                    pause(1);
                end
                
                app.message = app.waitForResponse();
                app.values = splitlines(app.message);
                app.values(end) = [];
                app.values = split(app.values);
                app.values = str2double(app.values);
                app.WriteMessage("Meranie prebehlo úspešne");
                app.AccWasMeasured = true;
                app.StartMeasurement.Enable = true;
                app.smValues_tlmene = smooth(app.values(:,2));
                app.smValues_netlmene = smooth(app.values(:,1));
                set(app.UIAxes_2,'XLim',[0 XlimPlot]);
                plot(app.UIAxes_2,(0:0.001:((length(app.values)-1)/1000))',app.values(:,1),(0:0.001:((length(app.values)-1)/1000))',app.values(:,2), [0 ((length(app.values)-1)/1000)], [-9.81 -9.81], 'LineWidth', 1.5);
                legend(app.UIAxes_2, "Senzor - Neodpružená hmota", "Senzor - Odpružená hmota", "Gravitačné zrýchlenie");
                
            else
                app.WriteMessage("Spojenie je neaktívne");
                app.StartMeasurement.Enable = true;
            end
        end

        % Button pushed function: SendMeasTimeButton
        function SendMeasTimeButtonPushed(app, event)
            if app.connected == true
                write(app.device, '0','char');   %Nastavenie doby merania
                app.WriteMessage("Zapisujem nové dáta ");
                for j = 1:app.timeout
                    if app.device.NumBytesAvailable == 0
                        app.status(app.i,:) = app.status(app.i,:) + ". ";
                        app.StatusArea.Value = app.status;
                        pause(1);
                    else
                        break;
                    end
                end
                if app.device.NumBytesAvailable ~= 0
                    if read(app.device,app.device.NumBytesAvailable, 'char') == '0' % 1-ka ako BYTE je 49
                        valueToSend = (app.MeasTimeEditField.Value-0.2)*10;
                        disp(int2str(valueToSend));
                        app.dobaMerania = app.MeasTimeEditField.Value + 2;
                        write(app.device, int2str(valueToSend),'char');
                        for j = 1:app.timeout
                            if app.device.NumBytesAvailable == 0
                                app.status(app.i,:) = app.status(app.i,:) + ". ";
                                app.StatusArea.Value = app.status;
                                pause(1);
                            else
                                break;
                            end
                        end
                        if app.device.NumBytesAvailable ~= 0
                            if read(app.device,app.device.NumBytesAvailable, 'char') == int2str(valueToSend)
                                app.WriteMessage("Dáta boli zapísané");
                            else
                                app.WriteMessage("Vyskytla sa chyba");
                            end
                        end
                        
                    end
                else
                    app.WriteMessage("Vyskytla sa chyba");
                end
            else
                app.WriteMessage("Spojenie je neaktívne");
            end
            
        end

        % Button pushed function: SimDataPlotButton
        function SimDataPlotButtonPushed(app, event)
            if app.simDataVisibility
                plot(app.UIAxes,(app.t.t)',app.simulationData_Wheel.neodAY, (0:0.001:((length(app.values)-1)/1000))',app.values(:,1), [0 length((app.t.t)')], [-9.81 -9.81], 'LineWidth', 1.5);
                legend(app.UIAxes, "Neodpružená hmota - simulácia", "Neodpružená hmota - meranie", "Gravitačné zrýchlenie");
                app.simDataVisibility = false;
                app.SimDataPlotButton.Text = "Zobraziť dáta odpruženej hmoty";
            else
                plot(app.UIAxes,(app.t.t)',app.simulationData_Car.odAY, (0:0.001:((length(app.values)-1)/1000))',app.values(:,2), [0 length((app.t.t)')], [-9.81 -9.81], 'LineWidth', 1.5);
                legend(app.UIAxes, "Odpružená hmota - simulácia", "Odpružená hmota - meranie", "Gravitačné zrýchlenie");
                app.simDataVisibility = true;
                app.SimDataPlotButton.Text = "Zobraziť dáta neodpruženej hmoty";
            end
        end

        % Button pushed function: SmoothDataButton
        function SmoothDataButtonPushed(app, event)
            if app.smoothDataVisible
                plot(app.UIAxes_2,(0:0.001:((length(app.values)-1)/1000))',app.values(:,1),(0:0.001:((length(app.values)-1)/1000))',app.values(:,2), [0 ((length(app.values)-1)/1000)], [-9.81 -9.81], 'LineWidth', 1.5)
                legend(app.UIAxes_2, "Senzor - Neodpružená hmota", "Senzor - Odpružená hmota", "Gravitačné zrýchlenie");
                app.smoothDataVisible = false;
                app.SmoothDataButton.Text = "Zobraziť vyhladené dáta";
            else
                plot(app.UIAxes_2,(0:0.001:((length(app.values)-1)/1000))',app.smValues_netlmene,(0:0.001:((length(app.values)-1)/1000))',app.smValues_tlmene, [0 ((length(app.values)-1)/1000)], [-9.81 -9.81], 'LineWidth', 1.5)
                legend(app.UIAxes_2, "Senzor - Neodpružená hmota", "Senzor - Odpružená hmota", "Gravitačné zrýchlenie");
                app.smoothDataVisible = true;
                app.SmoothDataButton.Text = "Zobraziť originálne dáta";
            end
        end

        % Button pushed function: ExportCSVPanel
        function EportCSVtoFile(app, event)
            if app.AccWasMeasured
                [file,path] = uiputfile('Meranie.csv');
                filename = fullfile(path,file);
                valuesToExport = [(0:0.001:((length(app.values)-1)/1000))' app.values];
                csvwrite(filename,valuesToExport);
            else
                app.WriteMessage("Dáta na export nie sú k dispozícii, vykonajte meranie");
            end
        end

        % Button pushed function: OpenExportPanel
        function OpenExportPanelButtonPushed(app, event)
            app.ExportPanel.Visible = true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ExportPanel.Visible = false;
        end

        % Button pushed function: SendToWorkspaceButton
        function SendToWorkspaceButtonPushed(app, event)
            assignin('base', 'DataMerania', [(0:0.001:((length(app.values)-1)/1000))' app.values])
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            app.porovnanieDat = ~app.porovnanieDat;
            if app.porovnanieDat
                app.SimDataPlotButton.Enable = true;
                plot(app.UIAxes,(app.t.t)',app.simulationData_Car.odAY, (0:0.001:((length(app.values)-1)/1000))',app.values(:,2), [0 length((app.t.t)')], [-9.81 -9.81], 'LineWidth', 1.5);
                legend(app.UIAxes, "Odpružená hmota - simulácia", "Odpružená hmota - meranie", "Gravitačné zrýchlenie");
                app.simDataVisibility = true;
                app.SimDataPlotButton.Text = "Zobraziť dáta neodpruženej hmoty";
                app.CompareButton.Text = "Zobraziť dáta simulácie";
            else
                app.SimDataPlotButton.Enable = false;
                set(app.UIAxes,'XLim',[0 0.6]);
                plot(app.UIAxes,(app.t.t)',app.simulationData_Wheel.neodAY, (app.t.t)',app.simulationData_Car20.odAY20, [0 length((app.t.t)')], [-9.81 -9.81], 'LineWidth', 1.5);
                legend(app.UIAxes, "Neodpružená hmota", "Odpružená hmota", "Gravitačné zrýchlenie");
                app.CompareButton.Text = "Zobraziť porovnanie";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuspensionModelApp = uifigure('Visible', 'off');
            app.SuspensionModelApp.Name = 'Riadenie fyzikálneho modelu';
            app.SuspensionModelApp.Icon = 'Ikona.png';
            app.ControllPanel = uipanel(app.SuspensionModelApp);
            app.ControllPanel.Title = 'OVLÁDANIE';
            app.KomunikanportDropDownLabel = uilabel(app.ControllPanel);
            app.KomunikanportDropDownLabel.Text = 'Komunikačný port:';
            app.SerialPortList = uidropdown(app.ControllPanel);
            app.SerialPortList.Items = {};
            app.SerialPortList.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortListOpening, true);
            app.SerialPortList.ValueChangedFcn = createCallbackFcn(app, @SerialPortListValueChanged, true);
            app.SerialPortList.Value = {};
            app.ConnectButton = uibutton(app.ControllPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'PRIPOJIŤ';
            app.StartMeasurement = uibutton(app.ControllPanel, 'push');
            app.StartMeasurement.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementPushed, true);
            app.StartMeasurement.Text = 'SPUSTIŤ MERANIE';
            app.StavpripojeniaLampLabel = uilabel(app.ControllPanel);
            app.StavpripojeniaLampLabel.Text = 'Stav pripojenia';
            app.ConnectionStatusLamp = uilamp(app.ControllPanel);
            app.StatusArea = uitextarea(app.ControllPanel);
            app.TypmeraniaSwitchLabel = uilabel(app.ControllPanel);
            app.TypmeraniaSwitchLabel.Text = 'Typ merania';
            app.MeasureTypeSwitch = uiswitch(app.ControllPanel, 'slider');
            app.MeasureTypeSwitch.Items = {'NORMAL', 'STEP'};
            app.MeasureTypeSwitch.Tooltip = {'Voľba typu merania:'; '    - STEP - meranie s čakaním pred zjazdom z obrubníka'; '    - NORMAL - kontinuálne meranie'};
            app.MeasureTypeSwitch.Value = 'NORMAL';
            app.OpenExportPanel = uibutton(app.ControllPanel, 'push');
            app.OpenExportPanel.ButtonPushedFcn = createCallbackFcn(app, @OpenExportPanelButtonPushed, true);
            app.OpenExportPanel.Text = 'Exportovať dáta merania';
            app.SettingsPanel = uipanel(app.SuspensionModelApp);
            app.SettingsPanel.Title = 'NASTAVENIA';
            app.DobaMeraniasLabel = uilabel(app.SettingsPanel);
            app.DobaMeraniasLabel.Text = 'Doba Merania [s]:';
            app.MeasTimeEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MeasTimeEditField.Limits = [0.6 0.9];
            app.MeasTimeEditField.ValueDisplayFormat = '%5.1f';
            app.MeasTimeEditField.Value = 0.6;
            app.SendMeasTimeButton = uibutton(app.SettingsPanel, 'push');
            app.SendMeasTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SendMeasTimeButtonPushed, true);
            app.SendMeasTimeButton.Text = 'Odoslať';
            app.SimDataPlotButton = uibutton(app.SuspensionModelApp, 'push');
            app.SimDataPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SimDataPlotButtonPushed, true);
            app.SimDataPlotButton.Enable = 'off';
            app.SimDataPlotButton.Text = 'Zobraziť dáta neodpruženej hmoty';
            app.SmoothDataButton = uibutton(app.SuspensionModelApp, 'push');
            app.SmoothDataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothDataButtonPushed, true);
            app.SmoothDataButton.Text = 'Zobraziť vyhladené dáta';
            app.ExportPanel = uipanel(app.SuspensionModelApp);
            app.ExportPanel.Title = 'Export';
            app.ExportPanel.Visible = 'off';
            app.Image = uiimage(app.ExportPanel);
            app.Image.ImageSource = 'MatlabVysvetlivkaCSV.png';
            app.UsporiadaniedtLabel = uilabel(app.ExportPanel);
            app.UsporiadaniedtLabel.Text = 'Usporiadanie dát';
            app.ExportCSVPanel = uibutton(app.ExportPanel, 'push');
            app.ExportCSVPanel.ButtonPushedFcn = createCallbackFcn(app, @EportCSVtoFile, true);
            app.ExportCSVPanel.Text = 'Exportovať do súboru';
            app.SendToWorkspaceButton = uibutton(app.ExportPanel, 'push');
            app.SendToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SendToWorkspaceButtonPushed, true);
            app.SendToWorkspaceButton.Text = 'Odoslať do "Workspace"';
            app.CloseButton = uibutton(app.ExportPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Zatvor';
            app.CompareButton = uibutton(app.SuspensionModelApp, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Zobraziť porovnanie';
            app.UIAxes = uiaxes(app.SuspensionModelApp);
            title(app.UIAxes, 'Simulácia')
            xlabel(app.UIAxes, 'Čas [s]')
            ylabel(app.UIAxes, 'Zrýchlenie [m/s^2]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.SuspensionModelApp);
            title(app.UIAxes_2, 'Fyzikálny model')
            xlabel(app.UIAxes_2, 'Čas [s]')
            ylabel(app.UIAxes_2, 'Zrýchlenie [m/s^2]')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.SuspensionModelApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DP_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SuspensionModelApp)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SuspensionModelApp)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuspensionModelApp)
        end
    end
end